classdef SMS_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab1                            matlab.ui.container.Tab
        MultiFrameAnalysisPanel         matlab.ui.container.Panel
        ExitGUIButton                   matlab.ui.control.Button
        CloseAllFiguresButton           matlab.ui.control.Button
        MultivariateGaussiansFitNumberofGaussiansEditField  matlab.ui.control.NumericEditField
        MultivariateGaussiansFitNumberofGaussiansLabel  matlab.ui.control.Label
        nBinsEditField                  matlab.ui.control.NumericEditField
        nBinsEditFieldLabel             matlab.ui.control.Label
        HistogramRateConstSumofGaussiansButton  matlab.ui.control.Button
        Lamp_4                          matlab.ui.control.Lamp
        CurveFitsButton                 matlab.ui.control.Button
        Lamp_3                          matlab.ui.control.Lamp
        IntensityVsTimePlotsButton      matlab.ui.control.Button
        ComputeButton                   matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        TimeIntervalBWFramesminEditField  matlab.ui.control.NumericEditField
        TimeIntervalBWFramesminEditFieldLabel  matlab.ui.control.Label
        TotalNumberofImagesEditField    matlab.ui.control.NumericEditField
        TotalNumberofImagesEditFieldLabel  matlab.ui.control.Label
        FilepathEditField               matlab.ui.control.EditField
        FilepathEditFieldLabel          matlab.ui.control.Label
        SingleFrameAnalysisPanel        matlab.ui.container.Panel
        CoordinateXEditField            matlab.ui.control.NumericEditField
        CoordinateXEditFieldLabel       matlab.ui.control.Label
        WellCountButton                 matlab.ui.control.Button
        CellposeSegmentationButton      matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        CellposeMaskButton              matlab.ui.control.Button
        LoadFirstFrameButton            matlab.ui.control.Button
        ROIHeightEditField              matlab.ui.control.NumericEditField
        ROIHeightEditFieldLabel         matlab.ui.control.Label
        ROIWidthEditField               matlab.ui.control.NumericEditField
        ROIWidthEditFieldLabel          matlab.ui.control.Label
        CoordinateYEditField            matlab.ui.control.NumericEditField
        CoordinateYEditFieldLabel       matlab.ui.control.Label
        Tab2                            matlab.ui.container.Tab
        MultiFrameDriftCheckAlongXYPanel  matlab.ui.container.Panel
        TotalNumberofImagesEditField_2  matlab.ui.control.NumericEditField
        TotalNumberofImagesEditField_2Label  matlab.ui.control.Label
        FilepathEditField_2             matlab.ui.control.EditField
        FilepathEditFieldLabel_2        matlab.ui.control.Label
        Lamp_5                          matlab.ui.control.Lamp
        PlotXOffsetYOffsetandCorrCoffButton  matlab.ui.control.Button
        DriftCheckAlongXYPanel          matlab.ui.container.Panel
        DisplayValuesLabel              matlab.ui.control.Label
        CorrCoef1to1EditField           matlab.ui.control.NumericEditField
        CorrCoef1to1EditFieldLabel      matlab.ui.control.Label
        YOffsetinPixelsEditField        matlab.ui.control.NumericEditField
        YOffsetinPixelsEditFieldLabel   matlab.ui.control.Label
        XOffsetinPixelsEditField        matlab.ui.control.NumericEditField
        XOffsetinPixelsEditFieldLabel   matlab.ui.control.Label
        NormalizedCrossCorrelationBWimagesButton  matlab.ui.control.Button
        LoadSecondImageROISecondImageButton  matlab.ui.control.Button
        ROIHeightEditField_2            matlab.ui.control.NumericEditField
        ROIHeightEditField_2Label       matlab.ui.control.Label
        ROIWidthEditField_2             matlab.ui.control.NumericEditField
        ROIWidthEditField_2Label        matlab.ui.control.Label
        CoordinateYEditField_2          matlab.ui.control.NumericEditField
        CoordinateYEditField_2Label     matlab.ui.control.Label
        CoordinateXEditField_2          matlab.ui.control.NumericEditField
        CoordinateXEditField_2Label     matlab.ui.control.Label
        LoadFirstImageROIfirstImageButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        I3 
        labelsDefault
        loverlayDefault
        L
        int_vec
        len_k
        pathname1 
        seg
        s
        Ik
        seg1
        coef_k
        ROI_DC_XY
        ROI_DC_XY_2
        position
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFirstFrameButton
        function LoadFirstFrameButtonPushed(app, event)
          app.pathname1 = ('C:\Users\Syd_R\OneDrive\Desktop\hemolysin_assays\19_02_2024 - run1\'); %Folder where your images are saved
          [fn, pn]=uigetfile('*.tiff','Load first frame'); 
          vv1=[pn,fn];
          x1=(imread(vv1));
          x1=mat2gray(im2double(x1(:,:,1)));
          app.I3=imcrop(x1,[app.CoordinateXEditField.Value app.CoordinateYEditField.Value  app.ROIWidthEditField.Value app.ROIHeightEditField.Value]);
          figure(1);imshow(x1); title('Full Field')  
          figure(2);imshow(app.I3); title('Grayscale ROI')     
        end

        % Button pushed function: CellposeMaskButton
        function CellposeMaskButtonPushed(app, event)
            %for i=1:100
            %    app.Gauge.Value=i;
            %    drawnow
            %end              
            app.Lamp.Color = [1 0 0] 
            pause(5)
            cp = cellpose(Model="cyto2");
            
            averageCellDiameter = 15;
            app.labelsDefault = segmentCells2D(cp,app.I3,ImageCellDiameter=averageCellDiameter);
            app.loverlayDefault = labeloverlay(app.I3,app.labelsDefault);
            figure(3);imshow(app.loverlayDefault); title('Cellpose Mask')

            app.Lamp.Color = [0 1 0] 
            app.Lamp.Enable = 'on';  
            
        end

        % Button pushed function: CellposeSegmentationButton
        function CellposeSegmentationButtonPushed(app, event)
            app.L=logical(app.labelsDefault);
            app.seg=app.L.*app.I3;
            figure(4);imshow(app.seg); colormap jet; shading interp; axis tight; colorbar;  title('Cellpose Segmentation')
            surf(app.seg); colormap jet; shading interp; axis tight; colorbar;
      
        end

        % Button pushed function: WellCountButton
        function WellCountButtonPushed(app, event)
           app.s = regionprops(app.L, 'PixelIdxList','Centroid');
           k = 1:numel(app.s);
           app.len_k=length(k);
           figure(5); imshow(app.loverlayDefault);title(['Total number of wells in ROI =', ' ',num2str(app.len_k)])
           hold on
            for k = 1:numel(app.s)
                c = app.s(k).Centroid;
                text(c(1), c(2), sprintf('%d', k), ...
               'HorizontalAlignment', 'center', ...
               'VerticalAlignment', 'middle','Color','w','FontSize',8);
            end
            hold off
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            app.Lamp_2.Color = [1 0 0]
           pause(2)
            for m=1:app.TotalNumberofImagesEditField.Value
                I=imread([app.FilepathEditField.Value,'\',num2str(m),'.tiff']);  
                I2=mat2gray(im2double(I(:,:,1)));
                app.Ik=imcrop(I2,[app.CoordinateXEditField.Value app.CoordinateYEditField.Value  app.ROIWidthEditField.Value app.ROIHeightEditField.Value]);
                app.seg1=app.L.*app.Ik;
                for ii=1:length(app.s)
                    idx = app.s(ii).PixelIdxList;
                    zz=app.seg1(idx);
                    app.int_vec(m,ii)=mean(zz(:));
                end
            end 
            app.Lamp_2.Color = [0 1 0] 
            app.Lamp_2.Enable = 'on'; 
        end

        % Button pushed function: IntensityVsTimePlotsButton
        function IntensityVsTimePlotsButtonPushed(app, event)
            app.Lamp_3.Color = [1 0 0]  
            pause(1)
            total_time =0:app.TimeIntervalBWFramesminEditField.Value:((app.TimeIntervalBWFramesminEditField.Value * app.TotalNumberofImagesEditField.Value)-1);
            total_time=total_time.*60;
            cmap = jet(length(app.int_vec));            
            for kk=1:length(app.int_vec)
                figure(6);
                p=plot(total_time',app.int_vec(:,kk));
                p.Color=[cmap(kk,1) cmap(kk,2) cmap(kk,3)];  
                hold on;
                xlabel('Time (sec)')
                ylabel('Normalized Intensity')
                title(['Intensity Vs. Time plot for ',num2str(app.len_k),' ','wells.'])
                fontsize(16,"points");
                axis tight;
            end
            
            app.Lamp_3.Color = [0 1 0] 
            app.Lamp_3.Enable = 'on'; 
        end

        % Button pushed function: CurveFitsButton
        function CurveFitsButtonPushed(app, event)
            app.Lamp_4.Color = [1 0 0]  
            pause(1)
            total_time =0:app.TimeIntervalBWFramesminEditField.Value:((app.TimeIntervalBWFramesminEditField.Value * app.TotalNumberofImagesEditField.Value)-1);
            total_time=total_time.*60;
            cmap = jet(length(app.int_vec));            
            for kk=1:length(app.int_vec)
                f= fit(total_time',app.int_vec(:,kk),'exp2');
                coef_c_1(:,kk)=f.a;
                coef_c_2(:,kk) =f.c*exp(f.d*total_time);
                app.coef_k(:,kk) = f.b;
                figure(7);
                p=plot(f);
                p.Color=[cmap(kk,1) cmap(kk,2) cmap(kk,3)];
                xlim([0 max(total_time)])
                ylim([0 max(app.int_vec(:))+0.1])
                hold on
                title('Exponential Curve Fits =')
                legend off;
            end
            app.Lamp_4.Color = [0 1 0] 
            app.Lamp_4.Enable = 'on'; 
        end

        % Button pushed function: HistogramRateConstSumofGaussiansButton
        function HistogramRateConstSumofGaussiansButtonPushed(app, event)
            IK=abs(app.coef_k);
            nbins=app.nBinsEditField.Value;
            gmdist = fitgmdist(IK', 1);
            %gmsigma = gmdist.Sigma;
            %gmmu = gmdist.mu;
            %gmwt = gmdist.ComponentProportion;

            figure(8);
            h=histogram(IK,nbins,'Normalization', 'pdf');
            h.FaceColor = [1 0.5 0];
            h.LineWidth =0.5;
            h.EdgeColor = 'k';
            x = 0:0.0001:round(max(IK)*3,2);
            xlim([0 round(max(IK)*3,2)])
            %ylim([0 200])
            hold on;        
            plot(x, pdf(gmdist, x'), 'k')
            xlabel('Rate constant K (sec.^{-1})')
            ylabel('Frequency')
            title(['Rate constant K for ',num2str(app.len_k),' ','wells.'])
        end

        % Button pushed function: CloseAllFiguresButton
        function CloseAllFiguresButtonPushed(app, event)
      
            close('all');
        end

        % Button pushed function: ExitGUIButton
        function ExitGUIButtonPushed(app, event)
            close all force;
        end

        % Button pushed function: LoadFirstImageROIfirstImageButton
        function LoadFirstImageROIfirstImageButtonPushed(app, event)
         
            [fn, pn]=uigetfile('*.tiff','Load First Frame for Drift Check');
            vv1=[pn,fn];
            d1=(imread(vv1));
            d2=mat2gray(im2double(d1(:,:,1)));
            d3_crop=imcrop(d2,[app.CoordinateXEditField_2.Value app.CoordinateYEditField_2.Value app.ROIWidthEditField_2.Value app.ROIHeightEditField_2.Value]);
            figure (9), imshow(d3_crop); title('Grayscale ROI (Select ROI)')
            h = imrect(gca,[10 10 100 100]);
            app.position = wait(h); % returns coordinates in "position" when user doubleclicks on rectangle
            app.ROI_DC_XY=imcrop(d3_crop,app.position);

            % ROI & ROI of Grayscale ROI
            figure(10);
            subplot(121); imshow(d3_crop); title('Grayscale ROI')
            hold all;  % hold image and plot rectangle on top
            rectangle('Position', app.position,'EdgeColor','y','LineWidth',2);
            subplot(122); imshow(app.ROI_DC_XY); title('ROI of Grayscale ROI')
        end

        % Button pushed function: LoadSecondImageROISecondImageButton
        function LoadSecondImageROISecondImageButtonPushed(app, event)
            %%%% Load second image
            [fn, pn]=uigetfile('*.tiff','Load second Frame for Drift Check');
            vv1_2=[pn,fn];
            d1_2=(imread(vv1_2));
            d2_2=mat2gray(im2double(d1_2(:,:,1)));
            d3_crop_2=imcrop(d2_2,[app.CoordinateXEditField_2.Value app.CoordinateYEditField_2.Value app.ROIWidthEditField_2.Value app.ROIHeightEditField_2.Value]);
            app.ROI_DC_XY_2=imcrop(d3_crop_2,app.position);
            
            % ROI & ROI of Grayscale ROI
            figure(11);
            subplot(121); imshow(d3_crop_2); title('Grayscale ROI')
            hold all;  % hold image and plot rectangle on top
            rectangle('Position', app.position,'EdgeColor','y','LineWidth',2);
            subplot(122); imshow(app.ROI_DC_XY_2); title('ROI of Grayscale ROI')
        end

        % Button pushed function: NormalizedCrossCorrelationBWimagesButton
        function NormalizedCrossCorrelationBWimagesButtonPushed(app, event)
            c = normxcorr2(app.ROI_DC_XY,app.ROI_DC_XY_2);
            [max_c, imax] = max(abs(c(:))); %find the max value
            [ypeak, xpeak] = ind2sub(size(c),imax(1)); %Find peak in cross-correlation.
            corr_offset = round([(xpeak-(size(c,2)+1)/2) (ypeak-(size(c,1)+1)/2)]);
            offset = corr_offset;
            xoffset = offset(1);
            yoffset = offset(2);
            app.XOffsetinPixelsEditField.Value=xoffset;
            app.YOffsetinPixelsEditField.Value=yoffset;
            app.CorrCoef1to1EditField.Value= max_c;


        end

        % Button pushed function: PlotXOffsetYOffsetandCorrCoffButton
        function PlotXOffsetYOffsetandCorrCoffButtonPushed(app, event)
            app.Lamp_5.Color = [1 0 0]  
            pause(1)

            for m=1:app.TotalNumberofImagesEditField_2.Value
                I_x=imread([app.FilepathEditField_2.Value,'\',num2str(m),'.tiff']);
                I2_x=(im2double(I_x(:,:,1)));
                I3_x=imcrop(I2_x,[app.CoordinateXEditField_2.Value app.CoordinateYEditField_2.Value app.ROIWidthEditField_2.Value app.ROIHeightEditField_2.Value]);
                if exist('position_1')==0
                    
                    figure (31);imshow (I3_x);
                    h = imrect(gca,[10 10 100 100]);
                    position_1 = wait(h); % returns coordinates in "position" when user doubleclicks on rectangle
                    Frame1=imcrop(I3_x,position_1);

                    c = normxcorr2(Frame1,Frame1);
                    [max_c, imax] = max(abs(c(:))); %find the max value
                    [ypeak, xpeak] = ind2sub(size(c),imax(1)); %Find peak in cross-correlation.
                    corr_offset = round([(xpeak-(size(c,2)+1)/2) (ypeak-(size(c,1)+1)/2)]);
                    offset = corr_offset;
                    xoffset(1,m) = offset(1);
                    yoffset(1,m) = offset(2);
                    corr_coef(1,m)=max_c;

                else
                    imshow(I3_x)
                    Frame2 = imcrop(I3_x,[position_1(:,1) position_1(:,2) position_1(:,3) position_1(:,4)]);
                    c = normxcorr2(Frame1,Frame2);
                    [max_c, imax] = max(abs(c(:))); %find the max value
                    [ypeak, xpeak] = ind2sub(size(c),imax(1)); %Find peak in cross-correlation.
                    corr_offset = round([(xpeak-(size(c,2)+1)/2) (ypeak-(size(c,1)+1)/2)]);
                    offset = corr_offset;
                    xoffset(1,m) = offset(1);
                    yoffset(1,m) = offset(2);
                    corr_coef(1,m)=max_c;
                end

            end    

            figure(12)
            plot(corr_coef,'LineWidth',2,'Color','r');
            xlabel('Time (sec)')
            ylabel('Corr. coef.')
            title('Corr. Coef. Vs. Time plot')
            fontsize(16,"points");
            axis tight;

            figure(13)
            plot(xoffset,'LineWidth',2,'Color','g');hold on;
            plot(yoffset, 'LineWidth',2,'Color','b');hold off;
            xlabel('Time (sec)')
            ylabel('Xoffset, Yoffset (in Pixels)')
            title('Xoffset, Yoffset Vs. Time plot')
            fontsize(16,"points");
            legend ('Xoffset','Yoffset')
            axis tight;

            save position_1
            app.Lamp_5.Color = [0 1 0] 
            app.Lamp_5.Enable = 'on'; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Tab1';
            app.SingleFrameAnalysisPanel = uipanel(app.Tab1);
            app.SingleFrameAnalysisPanel.Title = 'Single Frame Analysis';
            app.CoordinateYEditFieldLabel = uilabel(app.SingleFrameAnalysisPanel);
            app.CoordinateYEditFieldLabel.Text = 'Coordinate Y';
            app.CoordinateYEditField = uieditfield(app.SingleFrameAnalysisPanel, 'numeric');
            app.ROIWidthEditFieldLabel = uilabel(app.SingleFrameAnalysisPanel);
            app.ROIWidthEditFieldLabel.Text = 'ROI Width';
            app.ROIWidthEditField = uieditfield(app.SingleFrameAnalysisPanel, 'numeric');
            app.ROIHeightEditFieldLabel = uilabel(app.SingleFrameAnalysisPanel);
            app.ROIHeightEditFieldLabel.Text = 'ROI Height';
            app.ROIHeightEditField = uieditfield(app.SingleFrameAnalysisPanel, 'numeric');
            app.LoadFirstFrameButton = uibutton(app.SingleFrameAnalysisPanel, 'push');
            app.LoadFirstFrameButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFirstFrameButtonPushed, true);
            app.LoadFirstFrameButton.Text = 'Load First Frame';
            app.CellposeMaskButton = uibutton(app.SingleFrameAnalysisPanel, 'push');
            app.CellposeMaskButton.ButtonPushedFcn = createCallbackFcn(app, @CellposeMaskButtonPushed, true);
            app.CellposeMaskButton.Text = 'Cellpose Mask';
            app.Lamp = uilamp(app.SingleFrameAnalysisPanel);
            app.CellposeSegmentationButton = uibutton(app.SingleFrameAnalysisPanel, 'push');
            app.CellposeSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @CellposeSegmentationButtonPushed, true);
            app.CellposeSegmentationButton.Text = 'Cellpose Segmentation';
            app.WellCountButton = uibutton(app.SingleFrameAnalysisPanel, 'push');
            app.WellCountButton.ButtonPushedFcn = createCallbackFcn(app, @WellCountButtonPushed, true);
            app.WellCountButton.Text = 'Well Count';
            app.CoordinateXEditFieldLabel = uilabel(app.SingleFrameAnalysisPanel);
            app.CoordinateXEditFieldLabel.Text = 'Coordinate X';
            app.CoordinateXEditField = uieditfield(app.SingleFrameAnalysisPanel, 'numeric');
            app.MultiFrameAnalysisPanel = uipanel(app.Tab1);
            app.MultiFrameAnalysisPanel.Title = 'Multi Frame Analysis';
            app.FilepathEditFieldLabel = uilabel(app.MultiFrameAnalysisPanel);
            app.FilepathEditFieldLabel.Text = 'Filepath';
            app.FilepathEditField = uieditfield(app.MultiFrameAnalysisPanel, 'text');
            app.TotalNumberofImagesEditFieldLabel = uilabel(app.MultiFrameAnalysisPanel);
            app.TotalNumberofImagesEditFieldLabel.Text = {'Total Number '; 'of Images'};
            app.TotalNumberofImagesEditField = uieditfield(app.MultiFrameAnalysisPanel, 'numeric');
            app.TimeIntervalBWFramesminEditFieldLabel = uilabel(app.MultiFrameAnalysisPanel);
            app.TimeIntervalBWFramesminEditFieldLabel.Text = {'Time Interval'; 'B/W Frames'; '(min.)'};
            app.TimeIntervalBWFramesminEditField = uieditfield(app.MultiFrameAnalysisPanel, 'numeric');
            app.Lamp_2 = uilamp(app.MultiFrameAnalysisPanel);
            app.ComputeButton = uibutton(app.MultiFrameAnalysisPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.IntensityVsTimePlotsButton = uibutton(app.MultiFrameAnalysisPanel, 'push');
            app.IntensityVsTimePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @IntensityVsTimePlotsButtonPushed, true);
            app.IntensityVsTimePlotsButton.Text = {'Intensity Vs. '; 'Time Plots'};
            app.Lamp_3 = uilamp(app.MultiFrameAnalysisPanel);
            app.CurveFitsButton = uibutton(app.MultiFrameAnalysisPanel, 'push');
            app.CurveFitsButton.ButtonPushedFcn = createCallbackFcn(app, @CurveFitsButtonPushed, true);
            app.CurveFitsButton.Text = 'Curve Fits';
            app.Lamp_4 = uilamp(app.MultiFrameAnalysisPanel);
            app.HistogramRateConstSumofGaussiansButton = uibutton(app.MultiFrameAnalysisPanel, 'push');
            app.HistogramRateConstSumofGaussiansButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramRateConstSumofGaussiansButtonPushed, true);
            app.HistogramRateConstSumofGaussiansButton.Text = {'Histogram Rate Const. '; '& Sum of Gaussians'};
            app.nBinsEditFieldLabel = uilabel(app.MultiFrameAnalysisPanel);
            app.nBinsEditFieldLabel.Text = 'nBins';
            app.nBinsEditField = uieditfield(app.MultiFrameAnalysisPanel, 'numeric');
            app.MultivariateGaussiansFitNumberofGaussiansLabel = uilabel(app.MultiFrameAnalysisPanel);
            app.MultivariateGaussiansFitNumberofGaussiansLabel.Text = {'Multivariate  '; 'Gaussians Fit'; '(Number'; ' of Gaussians)'};
            app.MultivariateGaussiansFitNumberofGaussiansEditField = uieditfield(app.MultiFrameAnalysisPanel, 'numeric');
            app.CloseAllFiguresButton = uibutton(app.MultiFrameAnalysisPanel, 'push');
            app.CloseAllFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllFiguresButtonPushed, true);
            app.CloseAllFiguresButton.Text = {'Close All '; 'Figures'};
            app.ExitGUIButton = uibutton(app.MultiFrameAnalysisPanel, 'push');
            app.ExitGUIButton.ButtonPushedFcn = createCallbackFcn(app, @ExitGUIButtonPushed, true);
            app.ExitGUIButton.Text = 'Exit GUI';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.DriftCheckAlongXYPanel = uipanel(app.Tab2);
            app.DriftCheckAlongXYPanel.Title = 'Drift Check Along X-Y';
            app.LoadFirstImageROIfirstImageButton = uibutton(app.DriftCheckAlongXYPanel, 'push');
            app.LoadFirstImageROIfirstImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFirstImageROIfirstImageButtonPushed, true);
            app.LoadFirstImageROIfirstImageButton.Text = {'Load First Image'; '(ROI first Image)'};
            app.CoordinateXEditField_2Label = uilabel(app.DriftCheckAlongXYPanel);
            app.CoordinateXEditField_2Label.Text = 'Coordinate X';
            app.CoordinateXEditField_2 = uieditfield(app.DriftCheckAlongXYPanel, 'numeric');
            app.CoordinateYEditField_2Label = uilabel(app.DriftCheckAlongXYPanel);
            app.CoordinateYEditField_2Label.Text = 'Coordinate Y';
            app.CoordinateYEditField_2 = uieditfield(app.DriftCheckAlongXYPanel, 'numeric');
            app.ROIWidthEditField_2Label = uilabel(app.DriftCheckAlongXYPanel);
            app.ROIWidthEditField_2Label.Text = 'ROI Width';
            app.ROIWidthEditField_2 = uieditfield(app.DriftCheckAlongXYPanel, 'numeric');
            app.ROIHeightEditField_2Label = uilabel(app.DriftCheckAlongXYPanel);
            app.ROIHeightEditField_2Label.Text = 'ROI Height';
            app.ROIHeightEditField_2 = uieditfield(app.DriftCheckAlongXYPanel, 'numeric');
            app.LoadSecondImageROISecondImageButton = uibutton(app.DriftCheckAlongXYPanel, 'push');
            app.LoadSecondImageROISecondImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSecondImageROISecondImageButtonPushed, true);
            app.LoadSecondImageROISecondImageButton.Text = {'Load Second Image'; '(ROI Second Image)'};
            app.NormalizedCrossCorrelationBWimagesButton = uibutton(app.DriftCheckAlongXYPanel, 'push');
            app.NormalizedCrossCorrelationBWimagesButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizedCrossCorrelationBWimagesButtonPushed, true);
            app.NormalizedCrossCorrelationBWimagesButton.Text = {'Normalized '; 'Cross-Correlation'; 'B/W images'};
            app.XOffsetinPixelsEditFieldLabel = uilabel(app.DriftCheckAlongXYPanel);
            app.XOffsetinPixelsEditFieldLabel.Text = {'XOffset'; '(in Pixels)'};
            app.XOffsetinPixelsEditField = uieditfield(app.DriftCheckAlongXYPanel, 'numeric');
            app.YOffsetinPixelsEditFieldLabel = uilabel(app.DriftCheckAlongXYPanel);
            app.YOffsetinPixelsEditFieldLabel.Text = {'YOffset'; '(in Pixels)'};
            app.YOffsetinPixelsEditField = uieditfield(app.DriftCheckAlongXYPanel, 'numeric');
            app.CorrCoef1to1EditFieldLabel = uilabel(app.DriftCheckAlongXYPanel);
            app.CorrCoef1to1EditFieldLabel.Text = {'Corr. Coef.'; '(-1 to +1)'};
            app.CorrCoef1to1EditField = uieditfield(app.DriftCheckAlongXYPanel, 'numeric');
            app.DisplayValuesLabel = uilabel(app.DriftCheckAlongXYPanel);
            app.DisplayValuesLabel.Text = 'Display Values';
            app.MultiFrameDriftCheckAlongXYPanel = uipanel(app.Tab2);
            app.MultiFrameDriftCheckAlongXYPanel.Title = 'Multi Frame Drift Check Along X-Y';
            app.PlotXOffsetYOffsetandCorrCoffButton = uibutton(app.MultiFrameDriftCheckAlongXYPanel, 'push');
            app.PlotXOffsetYOffsetandCorrCoffButton.ButtonPushedFcn = createCallbackFcn(app, @PlotXOffsetYOffsetandCorrCoffButtonPushed, true);
            app.PlotXOffsetYOffsetandCorrCoffButton.Text = {'Plot XOffset,'; 'YOffset and '; 'Corr. Coff'};
            app.Lamp_5 = uilamp(app.MultiFrameDriftCheckAlongXYPanel);
            app.FilepathEditFieldLabel_2 = uilabel(app.MultiFrameDriftCheckAlongXYPanel);
            app.FilepathEditFieldLabel_2.Text = 'Filepath';
            app.FilepathEditField_2 = uieditfield(app.MultiFrameDriftCheckAlongXYPanel, 'text');
            app.TotalNumberofImagesEditField_2Label = uilabel(app.MultiFrameDriftCheckAlongXYPanel);
            app.TotalNumberofImagesEditField_2Label.Text = {'Total Number '; 'of Images'};
            app.TotalNumberofImagesEditField_2 = uieditfield(app.MultiFrameDriftCheckAlongXYPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SMS_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end